% description: Model soft robots using finite element method and design feedback control laws.
% This plugin is distributed under the GNU LGPL (Lesser General Public License) license with the same conditions than SOFA.
% Contributors: Defrost team  (INRIA, University of Lille, CNRS, Ecole Centrale de Lille)
% Contact information: https://project.inria.fr/softrobot/contact/
% description: Design and Control Soft Robots.
% This plugin is distributed under the GNU LGPL (Lesser General Public License) license with the same conditi
classdef SoftRobot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloseFiguresButton             matlab.ui.control.Button
        Status                         matlab.ui.control.EditField
        Info                           matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        ModellingTab                   matlab.ui.container.Tab
        SeeModellingCodeButton         matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        launchSimulationCheckBox_3     matlab.ui.control.CheckBox
        displayModeldataCheckBox       matlab.ui.control.CheckBox
        ValidateModelButton            matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        useSavedMatFileCheckBox        matlab.ui.control.CheckBox
        useExistingTextFilesCheckBox   matlab.ui.control.CheckBox
        launchSofaCheckBox             matlab.ui.control.CheckBox
        BuildNewModelButton            matlab.ui.control.Button
        ModelnameDropDown              matlab.ui.control.DropDown
        ModelnameDropDownLabel         matlab.ui.control.Label
        ReductionTab                   matlab.ui.container.Tab
        SeeReductionCodeButton         matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        ValidateReductionButton        matlab.ui.control.Button
        launchSimulationCheckBox       matlab.ui.control.CheckBox
        plotReductionResultsCheckBox   matlab.ui.control.CheckBox
        Panel_3                        matlab.ui.container.Panel
        useExistingSnapshotsCheckBox   matlab.ui.control.CheckBox
        launchSofaShakingCheckBox      matlab.ui.control.CheckBox
        PerformModelReductionButton    matlab.ui.control.Button
        ReducedOrderSpinner            matlab.ui.control.Spinner
        ReducedOrderSpinnerLabel       matlab.ui.control.Label
        ReductionMethodDropDown        matlab.ui.control.DropDown
        ReductionMethodDropDownLabel   matlab.ui.control.Label
        ControlTab                     matlab.ui.container.Tab
        SeeControlCodeButton           matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        launchSimulationCheckBox_2     matlab.ui.control.CheckBox
        plotClosedLoopResultsCheckBox  matlab.ui.control.CheckBox
        ValidatecontrollerButton       matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        ComputeFeedbackControllerButton  matlab.ui.control.Button
        SpecificControlMethodDropDown  matlab.ui.control.DropDown
        SpecificControlMethodDropDownLabel  matlab.ui.control.Label
        GenericControlMethodDropDown   matlab.ui.control.DropDown
        GenericControlMethodDropDownLabel  matlab.ui.control.Label
        ObserverTab                    matlab.ui.container.Tab
        SeeObserverCodeButton          matlab.ui.control.Button
        Panel_8                        matlab.ui.container.Panel
        plotObserverResultsCheckBox    matlab.ui.control.CheckBox
        ValidateobserverButton         matlab.ui.control.Button
        Panel_7                        matlab.ui.container.Panel
        ComputeObserverButton          matlab.ui.control.Button
        ObserverMethodDropDown         matlab.ui.control.DropDown
        ObserverMethodDropDownLabel    matlab.ui.control.Label
        TestinSOFATab                  matlab.ui.container.Tab
        SeeModelilingCodeButton_3      matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        SeeModelilingCodeButton_2      matlab.ui.control.Button
        Panel_10                       matlab.ui.container.Panel
        SimulationtolaunchDropDown     matlab.ui.control.DropDown
        SimulationtolaunchDropDownLabel  matlab.ui.control.Label
        TestinsofaButton               matlab.ui.control.Button
        Panel_9                        matlab.ui.container.Panel
        Send_ForceToDispCheckBox       matlab.ui.control.CheckBox
        SendObserverCheckBox           matlab.ui.control.CheckBox
        Send_FullCheckBox              matlab.ui.control.CheckBox
        SendFeedbackCheckBox           matlab.ui.control.CheckBox
        SendtosofaButton               matlab.ui.control.Button
        OverviewTab                    matlab.ui.container.Tab
        Panel_11                       matlab.ui.container.Panel
        TextArea_6                     matlab.ui.control.TextArea
        HelpButton                     matlab.ui.control.Button
        Panel_12                       matlab.ui.container.Panel
        LoadAppButton                  matlab.ui.control.Button
        SaveAppButton                  matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        TextArea_obs                   matlab.ui.control.TextArea
        TextArea_control               matlab.ui.control.TextArea
        TextArea_red                   matlab.ui.control.TextArea
        TextArea_name                  matlab.ui.control.TextArea
        MethodobsLabel                 matlab.ui.control.Label
        MethodcontrolLabel             matlab.ui.control.Label
        MethodredsizeredLabel          matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        env_var                     % link to sofa scenes and simulation data
        SoftRobot                   % object of type Soft Robot
        listOfDisabledButtons       % list of disabled push button in GUI
        listOfEnabledButtons        % list of enabled push button in GUI
        currentState                % current state of GUI, used to save and load GUI
    end
    
    methods (Access = private)
        
        function app = updateButtonsState(app)
            %UPDATEBUTTONSTATE is called each time a property of the soft robot (object of type SoftRobot_class)
            % is updated and UPDATEBUTTONSTATE changes the push button state accordingly.
            % Example:
            %   By default, only the 'BuildModel' button is enabled, once a model is done, UPDATEBUTTONSTATE is called
            %   and COMPUTEREDUCTION is enabled.
            hasName=app.currentState.hasName;
            modelIsBuilt=app.currentState.modelIsBuilt;
            reductionIsDone=app.currentState.reductionIsDone;
            hasController=app.currentState.hasController;
            hasObserver=app.currentState.hasObserver;
            
            % update enabled and disabled button according to the state of the app.
            if ~hasName
                app.listOfDisabledButtons = [app.BuildNewModelButton app.ValidateModelButton ...
                    app.PerformModelReductionButton app.ReducedOrderSpinner app.ValidateReductionButton...
                    app.ComputeFeedbackControllerButton app.ValidatecontrollerButton app.ComputeObserverButton...
                    app.ValidateobserverButton app.TestinsofaButton];
                app.listOfEnabledButtons = [];
            elseif  ~modelIsBuilt
                app.listOfDisabledButtons = [app.ValidateModelButton ...
                    app.PerformModelReductionButton app.ReducedOrderSpinner app.ValidateReductionButton ...
                    app.ComputeFeedbackControllerButton...
                    app.ValidatecontrollerButton app.ComputeObserverButton...
                    app.ValidateobserverButton app.TestinsofaButton];
                app.listOfEnabledButtons = [app.BuildNewModelButton];
            elseif modelIsBuilt && ~reductionIsDone
                app.listOfDisabledButtons = [app.ValidateReductionButton app.ComputeFeedbackControllerButton...
                    app.ValidatecontrollerButton app.ComputeObserverButton...
                    app.ValidateobserverButton app.TestinsofaButton];
                app.listOfEnabledButtons = [app.BuildNewModelButton app.ValidateModelButton ...
                    app.ReducedOrderSpinner app.PerformModelReductionButton];
            elseif all([modelIsBuilt,reductionIsDone]) && ~any([hasObserver,hasController])
                app.listOfDisabledButtons = [app.ValidatecontrollerButton, app.ValidateobserverButton];
                app.listOfEnabledButtons = [app.BuildNewModelButton app.ValidateModelButton...
                    app.PerformModelReductionButton app.ReducedOrderSpinner app.ValidateReductionButton ...
                    app.ComputeFeedbackControllerButton...
                    app.ComputeObserverButton, app.TestinsofaButton];
            elseif all([modelIsBuilt,reductionIsDone,hasController]) && hasObserver==0
                app.listOfDisabledButtons = [app.ValidateobserverButton];
                app.listOfEnabledButtons = [app.BuildNewModelButton app.ValidateModelButton...
                    app.PerformModelReductionButton app.ReducedOrderSpinner app.ValidatecontrollerButton ...
                    app.ValidateReductionButton...
                    app.ComputeFeedbackControllerButton app.ComputeObserverButton, app.TestinsofaButton];
            elseif all([modelIsBuilt,reductionIsDone,hasObserver]) && hasController==0
                app.listOfDisabledButtons = [app.ValidatecontrollerButton];
                app.listOfEnabledButtons = [app.BuildNewModelButton app.ValidateModelButton ...
                    app.PerformModelReductionButton app.ReducedOrderSpinner...
                    app.ValidateobserverButton, app.ValidateReductionButton app.ComputeFeedbackControllerButton,...
                    app.ComputeObserverButton, app.TestinsofaButton];
            elseif all([modelIsBuilt,reductionIsDone,hasObserver,hasController])
                app.listOfDisabledButtons = [];
                app.listOfEnabledButtons = [app.BuildNewModelButton app.ValidateModelButton ...
                    app.PerformModelReductionButton app.ReducedOrderSpinner...
                    app.ValidatecontrollerButton, app.ValidateReductionButton app.ComputeFeedbackControllerButton,...
                    app.ComputeObserverButton, app.ValidateobserverButton, app.TestinsofaButton];
            end
            
            set(app.listOfDisabledButtons, 'Enable', 'off');
            set(app.listOfEnabledButtons, 'Enable', 'on');
            
        end
        
        function saveData(app)
            %SAVEDATA save the data of the app in the 'savedModels' directory. 
            % One has to save the content of app.SoftRobot in the 'SoftRobot' temporary variable
            % because one cannot save directly app.SoftRobot.
            % This functionsaves only the data of the object SoftRobot. It does not save the current state of the GUI app.
            % To do so, refer to saveApp.
            SoftRobot=app.SoftRobot;    
            save(fullfile('savedModels',app.SoftRobot.name),'SoftRobot');
            disp('SoftRobot saved in ~/savedModels');
        end
        
        function saveApp(app)
            %SAVEAPP saves the current state of the GUI app. This include the data of the SoftRobot obeject, as in the SAVEDATA fuction,
            % but iit also includes the state of the app, as the lsit of enabled and disabled push buttons.
            save(fullfile('savedModels',strcat(app.SoftRobot.name,'_app')),'app');
            disp('App saved in ~/savedModels');
        end
        
        function [SoftRobot,currentState,listOfEnabledButtons,listOfDisabledButtons]=loadApp(app)
            %LOADAPP looks in the 'savedModels' directory if a file for the corresponding model exists.
            % The model is chosen through the 'SoftRobot.name' variable.
            pathToSavedApp = fullfile('savedModels',strcat(app.SoftRobot.name,'_app'));
            if exist(pathToSavedApp,'var')
            savedApp=load(pathToSavedApp,'app');
            savedApp=savedApp.app;
            listOfDisabledButtons=savedApp.listOfDisabledButtons;
            listOfEnabledButtons=savedApp.listOfEnabledButtons;
            SoftRobot=savedApp.SoftRobot;
            currentState=savedApp.currentState;
            else
                warning('[%s] %s',mfilename,'App not loaded : No app saved with specified name');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, env_var)
            %% General initialization
            app.UIFigure.Name='Soft Robotics Toolbox';
            app.Status.Value='Ready.';
            app.SoftRobot=SoftRobot_class;  % create an empty soft robot object     
            %% environment variable
            % contains the link to the sofa scenes and to some required libraries
            app.env_var = env_var;
            %% Update list of available model in drop down menu:
            availableModel=dir(app.env_var.scenes)';        % list of available model of robots
            possibleModel=cell(1,length(availableModel)-2);   % -2 because of '.' and '..'
            possibleModel{1}='';
            for i=3:length(availableModel)
                possibleModel{i-1}=availableModel(i).name;
            end
            app.ModelnameDropDown.Items=possibleModel;
            %% Set list of available reduction method
            app.ReductionMethodDropDown.Items={'','POD','ITIA','BT'}; % first blank entry is used to force the user to choose an option
            %% Set list of available control method
            app.GenericControlMethodDropDown.Items={'','nonLinear', 'largeScaleControl','reducedOrderControl','reducedWithLargeScaleProof'};
            %% Set list of available observer method
            app.ObserverMethodDropDown.Items={'','extendedObserver'};
            %% assign variables to workspace (i.e. save an empty SoftRobot object)
            assignin('base','SoftRobot',app.SoftRobot);
            app.currentState=struct('hasName',0,'modelIsBuilt',0,'reductionIsDone',0,'hasController',0,'hasObserver',0);
            updateButtonsState(app);
        end

        % Button pushed function: BuildNewModelButton
        function BuildNewModelButtonPushed(app, event)
            %% Close app gui to prevent freezing
            app.UIFigure.Visible = 'off'; 
            disp('App will come back after modelling.');
            %% Call modelling function of SoftRobot_class
            app.SoftRobot.name=app.ModelnameDropDown.Value;
            [app.SoftRobot, status]=SoftRobot_class.modelling(app.SoftRobot,app.env_var, ...
                app.launchSofaCheckBox.Value, app.useExistingTextFilesCheckBox.Value, app.useSavedMatFileCheckBox.Value);
            %% update app state
            app.UIFigure.Visible = 'on';
            app.Status.Value='Ready.';
            app.currentState.modelIsBuilt=status;
            if status
                disp('Modelling step finished.');
                disp('---------------------');
                app.Info.Value='Modelling step finished.';
            end
            updateButtonsState(app);
            %% Assign SoftRobot object to workspace
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: PerformModelReductionButton
        function PerformModelReductionButtonPushed(app, event)
            %% Call reduction function of SoftRobot_class 
            %% with reduction method specified in the GUI
            app.SoftRobot.r=app.ReducedOrderSpinner.Value;
            if app.useExistingSnapshotsCheckBox.Value 
                areSnapshotsFilesPresent = exist(fullfile(app.env_var.data,'/reduction/debug/stateFile.state'),'file') && ...
                                                exist(fullfile(app.env_var.data,'/reduction/debug/velocityStateFile.state'),'file');
                if ~areSnapshotsFilesPresent                    
                    prompt=('No Snapshots files found. Launch Sofa shaking simulation ?');
                    f = uifigure;
                    if isequal(uiconfirm(f,prompt,prompt),'OK')
                        app.launchSofaShakingCheckBox.Value = true;
                    else
                        app.launchSofaShakingCheckBox.Value = false;
                    end
                    app.useExistingSnapshotsCheckBox.Value = false;
                    delete(f);
                end
            end
            app.Info.Value='Reduction step launched. App will come back after.';
            app.Status.Value='Busy...';            
            disp('Reduction step launched. App will come back after.');
            %% Close app gui to prevent freezing
            app.UIFigure.Visible = 'off';  
            app.SoftRobot=SoftRobot_class.reduction(app.SoftRobot,app.env_var,app.launchSofaShakingCheckBox.Value,...
                app.useExistingSnapshotsCheckBox.Value);            
            app.Info.Value='Reduction step finished.';
            app.Status.Value='Ready.';
            app.currentState.reductionIsDone=app.SoftRobot.DataRed.status;
            updateButtonsState(app);            
            app.UIFigure.Visible = 'on';  
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: ComputeFeedbackControllerButton
        function ComputeFeedbackControllerButtonPushed(app, event)
            %% Call controller function of SoftRobot_class 
            %% with controller method specified in the GUI
            app.Info.Value='Controller computation launched.';
            app.Status.Value='Busy...';
            app.SoftRobot=SoftRobot_class.controller(app.SoftRobot);
            app.Info.Value='Controller computation finished.';
            app.Status.Value='Ready.';
            app.currentState.hasController=1;
            updateButtonsState(app);
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: ComputeObserverButton
        function ComputeObserverButtonPushed(app, event)
            %% Call observer function of SoftRobot_class 
            %% with observer method specified in the GUI
            app.Info.Value='Observer computation launched.';
            app.Status.Value='Busy...';
            app.SoftRobot=SoftRobot_class.observer(app.SoftRobot);
            app.Info.Value='Observer computation finished.';
            app.Status.Value='Ready.';
            app.currentState.hasObserver=1;
            updateButtonsState(app);
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: ValidateModelButton
        function ValidateModelButtonPushed(app, event)
            app.Info.Value='Model validation launched.';
            app.Status.Value='Busy...';
            largeScale.validateModel(app.SoftRobot,app.displayModeldataCheckBox.Value,app.launchSimulationCheckBox_3.Value);
            app.Info.Value='Validate model finished.';
            app.Status.Value='Ready.';
        end

        % Button pushed function: ValidateReductionButton
        function ValidateReductionButtonPushed(app, event)
            app.Info.Value='Reduction validation launched.';
            app.Status.Value='Busy...';
            modelReduction.validateReduction(app.SoftRobot,app.plotReductionResultsCheckBox.Value,app.launchSimulationCheckBox.Value);
            app.Info.Value='Validate reduction finished.';
            app.Status.Value='Ready.';
        end

        % Button pushed function: ValidatecontrollerButton
        function ValidatecontrollerButtonPushed(app, event)
            controller.validateController(app.SoftRobot,app.plotClosedLoopResultsCheckBox.Value,app.launchSimulationCheckBox_2.Value);
            app.Info.Value='Validate control finished.';
            app.Status.Value='Ready.';
        end

        % Button pushed function: ValidateobserverButton
        function ValidateobserverButtonPushed(app, event)
            stateObserver.validateObserver(app.SoftRobot,app.plotObserverResultsCheckBox.Value);
            app.Info.Value='Validate observer finished.';
            app.Status.Value='Ready.';
        end

        % Button pushed function: TestinsofaButton
        function TestinsofaButtonPushed(app, event)
            showSofaGUI=1;
            sofa.runSofaFromMatlab(app.SoftRobot.name,app.env_var,app.SimulationtolaunchDropDown.Value,showSofaGUI);
        end

        % Value changed function: ModelnameDropDown
        function ModelnameDropDownValueChanged(app, event)
            app.SoftRobot=SoftRobot_class; % if name change, reset all fields
            app.SoftRobot.name=app.ModelnameDropDown.Value;
            app.currentState.('hasName')=1;
            updateButtonsState(app);
            app.env_var.data = sprintf('SofaScenes/Projects/%s/data',app.SoftRobot.name);
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Value changed function: ReducedOrderSpinner
        function ReducedOrderSpinnerValueChanged(app, event)
            if app.SoftRobot.r>size(app.SoftRobot.SysLarge_c.a,1)
                app.SoftRobot.r=size(app.SoftRobot.SysLarge_c.a,1);
                app.ReducedOrderSpinner.Value=size(app.SoftRobot.SysLarge_c.a,1);
            end
            app.SoftRobot.r=app.ReducedOrderSpinner.Value;
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Value changed function: GenericControlMethodDropDown
        function GenericControlMethodDropDownValueChanged(app, event)
            %% Set list of available generic control method
            switch app.GenericControlMethodDropDown.Value
                case 'largeScaleControl'
                    app.SpecificControlMethodDropDown.Items={'','systune'};
                case 'reducedOrderControl'
                    app.SpecificControlMethodDropDown.Items={'','traj_track','polePlacement',...
                        'decayRate','systune'};
                case 'reducedWithLargeScaleProof'
                    app.SpecificControlMethodDropDown.Items={'dampingControl'};
            end
            app.SoftRobot.methodControl.general=app.GenericControlMethodDropDown.Value;
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Value changed function: ReductionMethodDropDown
        function ReductionMethodDropDownValueChanged(app, event)
            app.SoftRobot.methodRed=app.ReductionMethodDropDown.Value;
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Value changed function: ObserverMethodDropDown
        function ObserverMethodDropDownValueChanged(app, event)
            app.SoftRobot.methodObs = app.ObserverMethodDropDown.Value;
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            %% Display an overview of the state of the SoftRobot object
            %% in the last tab of the GUI
            if ~isempty(app.SoftRobot.name)
                app.TextArea_name.Value=char(app.SoftRobot.name);
            else
                app.TextArea_name.Value='None';
            end
            if isstruct(app.SoftRobot.DataRed)              % if reduction has been done
                app.TextArea_red.Value=[char(app.SoftRobot.methodRed) ', ' num2str(app.SoftRobot.r)];
            else
                app.TextArea_red.Value='Reduction not done yet';
            end
            if isstruct(app.SoftRobot.DataCL)               % if controller has been designed
                app.TextArea_control.Value=[char(app.SoftRobot.methodControl.general) ', ' char(app.SoftRobot.methodControl.specific)];
            else
                app.TextArea_control.Value='Controller not done yet';
            end
            if isstruct(app.SoftRobot.DataObs)
                app.TextArea_obs.Value=char(app.SoftRobot.methodObs);
            else
                app.TextArea_obs.Value='Observer not done yet';
            end
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: SpecificControlMethodDropDown
        function SpecificControlMethodDropDownValueChanged(app, event)
            app.SoftRobot.methodControl.specific = app.SpecificControlMethodDropDown.Value;
        end

        % Callback function
        function ReconditioningButtonPushed(app, event)
            tau=0.1;
            warning('[Reconditioning] find optimal tau for each robot');
            app.SoftRobot.SysLarge_c=largeScale.continuous.systemReconditioning(app.SoftRobot.SysLarge_c,tau);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% close GUI
            prompt3=('Save SoftRobot data before closing?');
            answer=uiconfirm(app.UIFigure,prompt3,'Save data before closing?',...
                'Options',{'Save and close','close','cancel'},...
                'DefaultOption',2,'CancelOption',3);
            if isequal(answer,'Save and close')
                saveData(app);
                delete(app);
            elseif isequal(answer,'close')
                delete(app);
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: SendtosofaButton
        function SendtosofaButtonPushed(app, event)
            %% Call function SENDTOSOFA with parameters specified in the GUI
            app.Info.Value='Writing the files...';
            app.Status.Value='Busy...';
            sofa.sendToSofa(app.SoftRobot,app.env_var,...
                app.SendFeedbackCheckBox.Value, app.SendObserverCheckBox.Value, app.Send_ForceToDispCheckBox.Value,...
                app.Send_FullCheckBox.Value);
            app.Info.Value='Files written.';
            app.Status.Value='Ready.' ;
            app.SimulationtolaunchDropDown.Items={'openLoop','closedLoop'};
        end

        % Button pushed function: SaveAppButton
        function SaveAppButtonPushed(app, event)
            saveApp(app);
        end

        % Button pushed function: LoadAppButton
        function LoadAppButtonPushed(app, event)
            [savedRobot,savedState,savedListEnabled,savedListDisabled]=loadApp(app);
            app.currentState=savedState;
            app.SoftRobot=savedRobot;
            app.listOfDisabledButtons=savedListDisabled;
            app.listOfEnabledButtons=savedListEnabled;
            app=updateButtonsState(app);
            assignin('base','SoftRobot',app.SoftRobot);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            addpath(genpath('docs'));
            doc;
        end

        % Button pushed function: SeeModellingCodeButton
        function SeeModellingCodeButtonPushed(app, event)
            open('largeScale.buildModel');
        end

        % Button pushed function: SeeReductionCodeButton
        function SeeReductionCodeButtonPushed(app, event)
            open('modelReduction.computeReduction');
        end

        % Button pushed function: SeeControlCodeButton
        function SeeControlCodeButtonPushed(app, event)
            open('controller.computeController');
        end

        % Button pushed function: SeeObserverCodeButton
        function SeeObserverCodeButtonPushed(app, event)
            open('stateObserver.computeObserver');
        end

        % Callback function
        function SeeLinkSofaCodeButtonPushed(app, event)
            open('sofa.sendToSofa');
        end

        % Button pushed function: SeeModelilingCodeButton_3
        function SeeModelilingCodeButton_3Pushed(app, event)
            open('sofa.runSofaFromMatlab')
        end

        % Value changed function: launchSofaCheckBox
        function launchSofaCheckBoxValueChanged(app, event)
            value = app.launchSofaCheckBox.Value;
            if value
                app.useExistingTextFilesCheckBox.Value = 0;
                app.useSavedMatFileCheckBox.Value = 0;
            end
        end

        % Value changed function: useExistingTextFilesCheckBox
        function useExistingTextFilesCheckBoxValueChanged(app, event)
            value = app.useExistingTextFilesCheckBox.Value;
            if value
                app.launchSofaCheckBox.Value = 0;
                app.useSavedMatFileCheckBox.Value = 0;
            end
            
        end

        % Value changed function: useSavedMatFileCheckBox
        function useSavedMatFileCheckBoxValueChanged(app, event)
            value = app.useSavedMatFileCheckBox.Value;
            if value
                app.useExistingTextFilesCheckBox.Value = 0;
                app.launchSofaCheckBox.Value = 0;
            end            
        end

        % Value changed function: launchSofaShakingCheckBox
        function launchSofaShakingCheckBoxValueChanged(app, event)
            value = app.launchSofaShakingCheckBox.Value;
            if value
                app.useExistingSnapshotsCheckBox.Value = 0;
            end    
        end

        % Value changed function: useExistingSnapshotsCheckBox
        function useExistingSnapshotsCheckBoxValueChanged(app, event)
            value = app.useExistingSnapshotsCheckBox.Value;
            if value
                app.launchSofaShakingCheckBox.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModellingTab = uitab(app.TabGroup);
            app.ModellingTab.Title = 'Modelling';
            app.Panel = uipanel(app.ModellingTab);
            app.ModelnameDropDownLabel = uilabel(app.Panel);
            app.ModelnameDropDownLabel.Tooltip = {'Select the robot you want to study'};
            app.ModelnameDropDownLabel.Text = 'Model name';
            app.ModelnameDropDown = uidropdown(app.Panel);
            app.ModelnameDropDown.Items = {''};
            app.ModelnameDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelnameDropDownValueChanged, true);
            app.ModelnameDropDown.Tooltip = {'Select the robot you want to study'};
            app.ModelnameDropDown.Value = '';
            app.BuildNewModelButton = uibutton(app.Panel, 'push');
            app.BuildNewModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildNewModelButtonPushed, true);
            app.BuildNewModelButton.Tooltip = {'Build linear time invariant (LTI) model'; 'Other actions are disabled while model is not built'};
            app.BuildNewModelButton.Text = 'Build New Model';
            app.launchSofaCheckBox = uicheckbox(app.Panel);
            app.launchSofaCheckBox.ValueChangedFcn = createCallbackFcn(app, @launchSofaCheckBoxValueChanged, true);
            app.launchSofaCheckBox.Text = 'launchSofa';
            app.launchSofaCheckBox.Value = true;
            app.useExistingTextFilesCheckBox = uicheckbox(app.Panel);
            app.useExistingTextFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @useExistingTextFilesCheckBoxValueChanged, true);
            app.useExistingTextFilesCheckBox.Text = 'useExistingTextFiles';
            app.useSavedMatFileCheckBox = uicheckbox(app.Panel);
            app.useSavedMatFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @useSavedMatFileCheckBoxValueChanged, true);
            app.useSavedMatFileCheckBox.Text = 'useSavedMatFile';
            app.Panel_2 = uipanel(app.ModellingTab);
            app.ValidateModelButton = uibutton(app.Panel_2, 'push');
            app.ValidateModelButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateModelButtonPushed, true);
            app.ValidateModelButton.Tooltip = {'Check modelling results'};
            app.ValidateModelButton.Text = 'Validate Model';
            app.displayModeldataCheckBox = uicheckbox(app.Panel_2);
            app.displayModeldataCheckBox.Text = 'displayModeldata';
            app.launchSimulationCheckBox_3 = uicheckbox(app.Panel_2);
            app.launchSimulationCheckBox_3.Text = 'launchSimulation';
            app.SeeModellingCodeButton = uibutton(app.ModellingTab, 'push');
            app.SeeModellingCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeModellingCodeButtonPushed, true);
            app.SeeModellingCodeButton.Tooltip = {'Open corresponding matlab code file'};
            app.SeeModellingCodeButton.Text = 'See code';
            app.ReductionTab = uitab(app.TabGroup);
            app.ReductionTab.Title = 'Reduction';
            app.Panel_3 = uipanel(app.ReductionTab);
            app.ReductionMethodDropDownLabel = uilabel(app.Panel_3);
            app.ReductionMethodDropDownLabel.Tooltip = {'Select reduction method'};
            app.ReductionMethodDropDownLabel.Text = 'Reduction Method:';
            app.ReductionMethodDropDown = uidropdown(app.Panel_3);
            app.ReductionMethodDropDown.Items = {''};
            app.ReductionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ReductionMethodDropDownValueChanged, true);
            app.ReductionMethodDropDown.Tooltip = {'Select reduction method'};
            app.ReductionMethodDropDown.Value = '';
            app.ReducedOrderSpinnerLabel = uilabel(app.Panel_3);
            app.ReducedOrderSpinnerLabel.Text = 'Reduced Order:';
            app.ReducedOrderSpinner = uispinner(app.Panel_3);
            app.ReducedOrderSpinner.Step = 2;
            app.ReducedOrderSpinner.Limits = [2 Inf];
            app.ReducedOrderSpinner.RoundFractionalValues = 'on';
            app.ReducedOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @ReducedOrderSpinnerValueChanged, true);
            app.ReducedOrderSpinner.Tooltip = {'Select reduced order size'};
            app.ReducedOrderSpinner.Value = 2;
            app.PerformModelReductionButton = uibutton(app.Panel_3, 'push');
            app.PerformModelReductionButton.ButtonPushedFcn = createCallbackFcn(app, @PerformModelReductionButtonPushed, true);
            app.PerformModelReductionButton.Text = 'Perform Model Reduction';
            app.launchSofaShakingCheckBox = uicheckbox(app.Panel_3);
            app.launchSofaShakingCheckBox.ValueChangedFcn = createCallbackFcn(app, @launchSofaShakingCheckBoxValueChanged, true);
            app.launchSofaShakingCheckBox.Text = 'launchSofaShaking';
            app.launchSofaShakingCheckBox.Value = true;
            app.useExistingSnapshotsCheckBox = uicheckbox(app.Panel_3);
            app.useExistingSnapshotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useExistingSnapshotsCheckBoxValueChanged, true);
            app.useExistingSnapshotsCheckBox.Text = 'useExistingSnapshots';
            app.Panel_4 = uipanel(app.ReductionTab);
            app.plotReductionResultsCheckBox = uicheckbox(app.Panel_4);
            app.plotReductionResultsCheckBox.Text = 'plotReductionResults';
            app.launchSimulationCheckBox = uicheckbox(app.Panel_4);
            app.launchSimulationCheckBox.Text = 'launchSimulation';
            app.launchSimulationCheckBox.Value = true;
            app.ValidateReductionButton = uibutton(app.Panel_4, 'push');
            app.ValidateReductionButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateReductionButtonPushed, true);
            app.ValidateReductionButton.Tooltip = {'Check model reduction results'};
            app.ValidateReductionButton.Text = 'Validate Reduction';
            app.SeeReductionCodeButton = uibutton(app.ReductionTab, 'push');
            app.SeeReductionCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeReductionCodeButtonPushed, true);
            app.SeeReductionCodeButton.Tooltip = {'Open corresponding matlab code file'};
            app.SeeReductionCodeButton.Text = 'See code';
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.Panel_5 = uipanel(app.ControlTab);
            app.GenericControlMethodDropDownLabel = uilabel(app.Panel_5);
            app.GenericControlMethodDropDownLabel.Text = 'Generic Control Method:';
            app.GenericControlMethodDropDown = uidropdown(app.Panel_5);
            app.GenericControlMethodDropDown.Items = {''};
            app.GenericControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @GenericControlMethodDropDownValueChanged, true);
            app.GenericControlMethodDropDown.Value = '';
            app.SpecificControlMethodDropDownLabel = uilabel(app.Panel_5);
            app.SpecificControlMethodDropDownLabel.Text = 'Specific Control Method:';
            app.SpecificControlMethodDropDown = uidropdown(app.Panel_5);
            app.SpecificControlMethodDropDown.Items = {''};
            app.SpecificControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificControlMethodDropDownValueChanged, true);
            app.SpecificControlMethodDropDown.Value = '';
            app.ComputeFeedbackControllerButton = uibutton(app.Panel_5, 'push');
            app.ComputeFeedbackControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeFeedbackControllerButtonPushed, true);
            app.ComputeFeedbackControllerButton.Text = 'Compute Feedback Controller';
            app.Panel_6 = uipanel(app.ControlTab);
            app.ValidatecontrollerButton = uibutton(app.Panel_6, 'push');
            app.ValidatecontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatecontrollerButtonPushed, true);
            app.ValidatecontrollerButton.Tooltip = {'Check controller design results'};
            app.ValidatecontrollerButton.Text = 'Validate controller';
            app.plotClosedLoopResultsCheckBox = uicheckbox(app.Panel_6);
            app.plotClosedLoopResultsCheckBox.Text = 'plotClosedLoopResults';
            app.launchSimulationCheckBox_2 = uicheckbox(app.Panel_6);
            app.launchSimulationCheckBox_2.Text = 'launchSimulation';
            app.launchSimulationCheckBox_2.Value = true;
            app.SeeControlCodeButton = uibutton(app.ControlTab, 'push');
            app.SeeControlCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeControlCodeButtonPushed, true);
            app.SeeControlCodeButton.Tooltip = {'Open corresponding matlab code file'};
            app.SeeControlCodeButton.Text = 'See code';
            app.ObserverTab = uitab(app.TabGroup);
            app.ObserverTab.Title = 'Observer';
            app.Panel_7 = uipanel(app.ObserverTab);
            app.ObserverMethodDropDownLabel = uilabel(app.Panel_7);
            app.ObserverMethodDropDownLabel.Text = 'Observer Method:';
            app.ObserverMethodDropDown = uidropdown(app.Panel_7);
            app.ObserverMethodDropDown.Items = {''};
            app.ObserverMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ObserverMethodDropDownValueChanged, true);
            app.ObserverMethodDropDown.Value = '';
            app.ComputeObserverButton = uibutton(app.Panel_7, 'push');
            app.ComputeObserverButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeObserverButtonPushed, true);
            app.ComputeObserverButton.Text = 'Compute Observer';
            app.Panel_8 = uipanel(app.ObserverTab);
            app.ValidateobserverButton = uibutton(app.Panel_8, 'push');
            app.ValidateobserverButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateobserverButtonPushed, true);
            app.ValidateobserverButton.Tooltip = {'Check observer design results'};
            app.ValidateobserverButton.Text = 'Validate observer';
            app.plotObserverResultsCheckBox = uicheckbox(app.Panel_8);
            app.plotObserverResultsCheckBox.Text = 'plotObserverResults';
            app.SeeObserverCodeButton = uibutton(app.ObserverTab, 'push');
            app.SeeObserverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeObserverCodeButtonPushed, true);
            app.SeeObserverCodeButton.Tooltip = {'Open corresponding matlab code file'};
            app.SeeObserverCodeButton.Text = 'See code';
            app.TestinSOFATab = uitab(app.TabGroup);
            app.TestinSOFATab.Title = 'Test in SOFA';
            app.Panel_9 = uipanel(app.TestinSOFATab);
            app.SendtosofaButton = uibutton(app.Panel_9, 'push');
            app.SendtosofaButton.ButtonPushedFcn = createCallbackFcn(app, @SendtosofaButtonPushed, true);
            app.SendtosofaButton.Tooltip = {'Select the data to send to SOFA to launch simulation.'};
            app.SendtosofaButton.Text = 'Send to sofa';
            app.SendFeedbackCheckBox = uicheckbox(app.Panel_9);
            app.SendFeedbackCheckBox.Text = 'SendFeedback';
            app.Send_FullCheckBox = uicheckbox(app.Panel_9);
            app.Send_FullCheckBox.Text = 'Send_Full';
            app.SendObserverCheckBox = uicheckbox(app.Panel_9);
            app.SendObserverCheckBox.Text = 'SendObserver';
            app.Send_ForceToDispCheckBox = uicheckbox(app.Panel_9);
            app.Send_ForceToDispCheckBox.Text = 'Send_ForceToDisp';
            app.Panel_10 = uipanel(app.TestinSOFATab);
            app.TestinsofaButton = uibutton(app.Panel_10, 'push');
            app.TestinsofaButton.ButtonPushedFcn = createCallbackFcn(app, @TestinsofaButtonPushed, true);
            app.TestinsofaButton.Tooltip = {'Launch SOFA simulation.'};
            app.TestinsofaButton.Text = 'Test in sofa';
            app.SimulationtolaunchDropDownLabel = uilabel(app.Panel_10);
            app.SimulationtolaunchDropDownLabel.Text = 'Simulation to launch:';
            app.SimulationtolaunchDropDown = uidropdown(app.Panel_10);
            app.SimulationtolaunchDropDown.Items = {''};
            app.SimulationtolaunchDropDown.Value = '';
            app.SeeModelilingCodeButton_2 = uibutton(app.TestinSOFATab, 'push');
            app.SeeModelilingCodeButton_2.Tooltip = {'Open corresponding matlab code file'};
            app.SeeModelilingCodeButton_2.Text = 'send data';
            app.EditField = uieditfield(app.TestinSOFATab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'See code:';
            app.SeeModelilingCodeButton_3 = uibutton(app.TestinSOFATab, 'push');
            app.SeeModelilingCodeButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeeModelilingCodeButton_3Pushed, true);
            app.SeeModelilingCodeButton_3.Tooltip = {'Open corresponding matlab code file'};
            app.SeeModelilingCodeButton_3.Text = 'test simu';
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.Panel_12 = uipanel(app.OverviewTab);
            app.UpdateButton = uibutton(app.Panel_12, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.NameLabel = uilabel(app.Panel_12);
            app.NameLabel.Text = 'Name';
            app.MethodredsizeredLabel = uilabel(app.Panel_12);
            app.MethodredsizeredLabel.Text = 'Method red, size red:';
            app.MethodcontrolLabel = uilabel(app.Panel_12);
            app.MethodcontrolLabel.Text = 'Method control:';
            app.MethodobsLabel = uilabel(app.Panel_12);
            app.MethodobsLabel.Text = 'Method obs:';
            app.TextArea_name = uitextarea(app.Panel_12);
            app.TextArea_red = uitextarea(app.Panel_12);
            app.TextArea_control = uitextarea(app.Panel_12);
            app.TextArea_obs = uitextarea(app.Panel_12);
            app.SaveDataButton = uibutton(app.Panel_12, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SaveAppButton = uibutton(app.Panel_12, 'push');
            app.SaveAppButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAppButtonPushed, true);
            app.SaveAppButton.Text = 'Save App';
            app.LoadAppButton = uibutton(app.Panel_12, 'push');
            app.LoadAppButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAppButtonPushed, true);
            app.LoadAppButton.Text = 'Load App';
            app.Panel_11 = uipanel(app.OverviewTab);
            app.HelpButton = uibutton(app.Panel_11, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Documentation will open in Matlab main window'};
            app.HelpButton.Text = 'Help';
            app.TextArea_6 = uitextarea(app.Panel_11);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'See readMe or click on help to get documentation: Supplemental software/Soft Robotics Toolbox'};
            app.Info = uieditfield(app.UIFigure, 'text');
            app.Status = uieditfield(app.UIFigure, 'text');
            app.CloseFiguresButton = uibutton(app.UIFigure, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Text = 'Close Figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoftRobot_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end